classdef CWAnalyzies < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        InputParametersPanel  matlab.ui.container.Panel
        UnitsButtonGroup      matlab.ui.container.ButtonGroup
        NormalizedButton      matlab.ui.control.RadioButton
        PhysicalButton        matlab.ui.control.RadioButton
        UIAxes                matlab.ui.control.UIAxes
        omega02piEditField    matlab.ui.control.NumericEditField
        omega02piLabel        matlab.ui.control.Label
        n0EditField           matlab.ui.control.NumericEditField
        n0EditFieldLabel      matlab.ui.control.Label
        SEditField            matlab.ui.control.NumericEditField
        SEditFieldLabel       matlab.ui.control.Label
        n2EditField           matlab.ui.control.NumericEditField
        n2EditFieldLabel      matlab.ui.control.Label
        FinessEditField       matlab.ui.control.NumericEditField
        FinessEditFieldLabel  matlab.ui.control.Label
        PowerWEditField       matlab.ui.control.NumericEditField
        PowerWLabel           matlab.ui.control.Label
        etaEditField          matlab.ui.control.NumericEditField
        etaEditFieldLabel     matlab.ui.control.Label
        kappa2piEditField     matlab.ui.control.NumericEditField
        kappa2piLabel         matlab.ui.control.Label
        ResetButton           matlab.ui.control.Button
        PlotButton            matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
                Res       =  Set_Up_Methods_For_Synchronization_Paper;

                Res.CW.In.eta         = app.etaEditField.Value;            
                
                Res.CW.In.omega_p     = app.omega02piEditField.Value*2*pi;        
                Res.CW.In.n2          = app.n2EditField.Value;
                Res.CW.In.S           = app.SEditField.Value;
                Res.CW.In.n0          = app.n0EditField.Value;
                
                Res.CW.In.gamma       = Res.CW.In.omega_p*Res.CW.In.n2...
                                            /(2*Res.CW.In.n0*Res.CW.In.S);
                
                Res.CW.In.Finess      = app.FinessEditField.Value;
                Res.CW.In.kappa       = app.kappa2piEditField.Value*2*pi;
                
                Res.CW.In.D(1)        = Res.CW.In.Finess*Res.CW.In.kappa;
                Res.CW.In.D(2)        = 0;
                
                Res.CW.In.range          = 100;
                Res.CW.In.P           = app.PowerWEditField.Value;  
%               F                 =  Res.CW.In.D(1)/Res.CW.In.kappa;
%               gam_H_2_kappa*pi*Res.CW.In.kappa/(Res.CW.In.gamma*F*Res.CW.In.eta); 
                Res.CW.In.N_mode  =   2^8;
    
                N                 =  1000;
                H2               = Res.CW.In.P*Res.CW.In.eta*Res.CW.In.Finess/pi/Res.CW.In.kappa*Res.CW.In.gamma;
                delta_vector    = linspace(-H2*0.2,H2*1.2,N);
                
                U_CW_g = NaN(N,3);
                U_CW_Psi = NaN(N,3);
                
                for i = 1:N
            
                      CaF = Res;
                      
                      CaF.CW.In.delta =  delta_vector(i)*CaF.CW.In.kappa;
                      
                      CaF.CW          = CaF.CW.Met.Solve(CaF.CW);     
                      U_CW_g(i,:)     = CaF.CW.Sol.g*CaF.CW.Eq.norm/CaF.CW.In.kappa;
                      U_CW_Psi(i,:)   = abs(CaF.CW.Sol.Psi).^2;        
                      
                end
                if app.PhysicalButton.Value == 1
                    
                    app.UIAxes.XLabel.String = '\delta/2\pi';
                    app.UIAxes.YLabel.String = 'Intracavity Power (W)';
                    app.UIAxes.FontName = 'Times';
                    app.UIAxes.TickLabelInterpreter = 'latex';
                    app.UIAxes.FontSize = 18;
                    norm_delta = CaF.CW.In.kappa/2/pi;
                    delta_vector = norm_delta*delta_vector;
                    U_CW_1 =U_CW_Psi;
                end
                if app.NormalizedButton.Value == 1
                    app.UIAxes.XLabel.String = '\delta/\kappa';
                    app.UIAxes.YLabel.String = 'g/\kappa';
                    app.UIAxes.FontName = 'Times';
                    app.UIAxes.TickLabelInterpreter = 'latex';
                    app.UIAxes.FontSize = 18;
                    norm_delta = 1;
                    delta_vector = norm_delta*delta_vector;
                    U_CW_1 =U_CW_g;
                    
                end
                
                delta_vector_1 = delta_vector(~isnan(U_CW_1(:,1)).' & delta_vector > 0.5*norm_delta);
                delta_vector_2 = delta_vector(~isnan(U_CW_1(:,2)).' & delta_vector > 0.5*norm_delta);
                delta_vector_3 = delta_vector(~isnan(U_CW_1(:,3)).' & delta_vector > 0.5*norm_delta);
                
                U_CW1          = U_CW_1(~isnan(U_CW_1(:,1)).' & delta_vector > 0.5*norm_delta,1);
                U_CW2          = U_CW_1(~isnan(U_CW_1(:,2)).' & delta_vector > 0.5*norm_delta,2);
                U_CW3          = U_CW_1(~isnan(U_CW_1(:,3)).' & delta_vector > 0.5*norm_delta,3);
                
                hold (app.UIAxes,'on');
                plot([delta_vector_1,fliplr(delta_vector_2),delta_vector_3],[U_CW1;flipud(U_CW2);U_CW3],'Color',[0,0,0],'Parent',app.UIAxes,"LineWidth",2);
                plot(delta_vector,U_CW_1,'Color',[0,0,0],'Parent',app.UIAxes,"LineWidth",2);
                
        end

        % Selection changed function: UnitsButtonGroup
        function UnitsButtonGroupSelectionChanged(app, event)

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes,'reset')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.InputParametersPanel = uipanel(app.GridLayout);
            app.InputParametersPanel.Title = 'Input Parameters';
            app.UnitsButtonGroup = uibuttongroup(app.GridLayout);
            app.UnitsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @UnitsButtonGroupSelectionChanged, true);
            app.UnitsButtonGroup.Title = 'Units';
            app.NormalizedButton = uiradiobutton(app.UnitsButtonGroup);
            app.NormalizedButton.Text = 'Normalized';
            app.NormalizedButton.Value = true;
            app.PhysicalButton = uiradiobutton(app.UnitsButtonGroup);
            app.PhysicalButton.Text = 'Physical';
            app.UIAxes = uiaxes(app.GridLayout);
            app.omega02piEditField = uieditfield(app.GridLayout, 'numeric');
            app.omega02piEditField.Limits = [0 Inf];
            app.omega02piEditField.Value = 200000000000000;
            app.omega02piLabel = uilabel(app.GridLayout);
            app.omega02piLabel.Text = 'omega0/2\pi ';
            app.n0EditField = uieditfield(app.GridLayout, 'numeric');
            app.n0EditField.Limits = [1 10];
            app.n0EditField.Value = 1.43;
            app.n0EditFieldLabel = uilabel(app.GridLayout);
            app.n0EditFieldLabel.Text = 'n0';
            app.SEditField = uieditfield(app.GridLayout, 'numeric');
            app.SEditField.Value = 1e-10;
            app.SEditFieldLabel = uilabel(app.GridLayout);
            app.SEditFieldLabel.Text = 'S';
            app.n2EditField = uieditfield(app.GridLayout, 'numeric');
            app.n2EditField.Limits = [0 Inf];
            app.n2EditField.Value = 1.71e-20;
            app.n2EditFieldLabel = uilabel(app.GridLayout);
            app.n2EditFieldLabel.Text = 'n2';
            app.FinessEditField = uieditfield(app.GridLayout, 'numeric');
            app.FinessEditField.Limits = [0 Inf];
            app.FinessEditField.Value = 1000000;
            app.FinessEditFieldLabel = uilabel(app.GridLayout);
            app.FinessEditFieldLabel.Text = 'Finess';
            app.PowerWEditField = uieditfield(app.GridLayout, 'numeric');
            app.PowerWEditField.Limits = [1e-12 Inf];
            app.PowerWEditField.Value = 1e-06;
            app.PowerWLabel = uilabel(app.GridLayout);
            app.PowerWLabel.Text = 'Power (W)';
            app.etaEditField = uieditfield(app.GridLayout, 'numeric');
            app.etaEditField.Limits = [0 1];
            app.etaEditField.Value = 0.5;
            app.etaEditFieldLabel = uilabel(app.GridLayout);
            app.etaEditFieldLabel.Text = 'eta';
            app.kappa2piEditField = uieditfield(app.GridLayout, 'numeric');
            app.kappa2piEditField.Value = 2000;
            app.kappa2piLabel = uilabel(app.GridLayout);
            app.kappa2piLabel.Text = 'kappa/2pi';
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.PlotButton = uibutton(app.GridLayout, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CWAnalyzies

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end